classdef getpoints < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DataAcquisitionFolderPathEditFieldLabel  matlab.ui.control.Label
        PathField                matlab.ui.control.EditField
        BrowseButton             matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        WarningLabel             matlab.ui.control.Label
        WarningLabel_2           matlab.ui.control.Label
        CamNumber12SpinnerLabel  matlab.ui.control.Label
        CamField                 matlab.ui.control.Spinner
        ClearButton              matlab.ui.control.Button
        Success                  matlab.ui.control.Label
        SelectPointsButton       matlab.ui.control.Button
        WarningLabel_3           matlab.ui.control.Label
        AutoDetect               matlab.ui.control.Button
        AutoDetectLabel          matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cam; % Description
        im; % Description
        pts; % Description
        path; % Description
        isLoaded; % Description
        points; % Description
        arePoints; % Description
    end 
    
    methods (Access = private)
        
        function [xvals, yvals] = select_points(app)
            xvals = nan(1,30);
            yvals = nan(1,30);
            for i=1:30
                point = drawpoint(app.UIAxes);
                xvals(i) = round(point.Position(1));
                yvals(i) = round(point.Position(2));
            end
            
        end
        
        function  r = print2file(app)
            xvals = nan(1,30);
            yvals = nan(1,30);
            for i=1:30
                xvals(i) = round(app.points.(['p', num2str(i)]).Position(1));
                yvals(i) = round(app.points.(['p', num2str(i)]).Position(2));
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cam = app.CamField.Value-1;
            app.path = nan;
            app.isLoaded = 0;
            app.points = nan;
            app.arePoints = 0;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.PathField.Value = uigetdir;
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            app.path = app.PathField.Value;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if isnan(app.path)
                app.WarningLabel_2.Text = 'Error: Specify path';
                pause(3)
                app.WarningLabel_2.Text = '';
            else
                folder_contents = dir(app.path);
                m = {folder_contents.name};
                isValid1 = sum(contains(m, ['cam',num2str(app.cam),'.dovi']));
                isValid2 = sum(contains(m, ['cam',num2str(app.cam),'.raw']));
                if (isValid1 > 0) && (isValid2 > 0)
                    app.LoadButton.Text = 'Loading...';
                    pause(0.5)
                    file = ['meas_s0_cam', num2str(app.cam), '.dovi'];
                    app.im = mean(read_dovi(fullfile(app.PathField.Value, file)), 3);
%                     imagesc(app.UIAxes, app.im, 'XData', [1 app.UIAxes.Position(3)], 'YData', [1 app.UIAxes.Position(4)]); 
                    imagesc(app.UIAxes, app.im); 
                    set(app.UIAxes, 'XLim', [0, size(app.im, 2)])
                    set(app.UIAxes, 'YLim', [0, size(app.im, 1)])
                    colormap(app.UIAxes, gray); 
                    app.LoadButton.Text = 'Load';
                    app.isLoaded = 1;
                else
                    app.WarningLabel_2.Text = 'Error: Data not found';
                    pause(3)
                    app.WarningLabel_2.Text = '';
                end
            end
            
        end

        % Value changed function: CamField
        function CamFieldValueChanged(app, event)
            app.cam = app.CamField.Value-1;
        end

        % Value changed function: PathField
        function PathFieldValueChanged(app, event)
            app.path = app.PathField.Value;            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes)
        end

        % Button pushed function: SelectPointsButton
        function SelectPointsButtonPushed(app, event)
           if app.isLoaded
                [xvals, yvals] = select_points(app);
                a = 0:29;
                A = [a; xvals; a; yvals];
                formatSpec = '\nx%d=%d\ny%d=%d';
                fname = 'patternpoints.ini';
                fid = fopen(fullfile(app.path, fname), 'w');
                fprintf(fid,'[General]');
                fprintf(fid,formatSpec, A);
                app.Success.Text = ['Success! File Saved to: ', fullfile(app.path, fname)];
                pause(5)
                app.Success.Text = '';
            else
                app.WarningLabel_3.Text = 'Error: No image loaded';
                pause(3)
                app.WarningLabel_3.Text = '';
            end
        end

        % Button pushed function: AutoDetect
        function AutoDetectButtonPushed(app, event)
            if app.isLoaded
                [imagePoints,boardSize] = detectCheckerboardPoints(app.im);
                if isequal(boardSize, [6 7])
                    p = round(imagePoints);
                    p = reshape(p, [5,6,2]); % cast to matrix
                    p = permute(p, [2,1,3]); % reorder from column based to row based for patternpoints
                    p = reshape(p, [30,2]);  % cast back to list of pairs
                    hold(app.UIAxes, "on")
                    for i=1:30
                        text(app.UIAxes, p(i,1)+5, p(i,2), num2str(i), 'color', 'r')
                    end
                    plot(app.UIAxes, p(:,1), p(:,2), 'r.', 'MarkerSize', 10)
    
                    a = 0:29;
                    A = [a; p(:,1)'; a; p(:,2)'];
                    formatSpec = '\nx%d=%d\ny%d=%d';
                    fname = 'patternpoints.ini';
                    fid = fopen(fullfile(app.path, fname), 'w');
                    fprintf(fid,'[General]');
                    fprintf(fid,formatSpec, A);
                    app.Success.Text = ['Success! File Saved to: ', fullfile(app.path, fname)];
                    pause(5)
                    app.Success.Text = '';
                else
                    app.AutoDetectLabel.Text = 'No 6x7 checkerboard detected';
                    pause(3)
                    app.AutoDetectLabel.Text = '';  
                end
            else
                app.AutoDetectLabel.Text = 'Error: No image loaded';
                pause(3)
                app.AutoDetectLabel.Text = '';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'C-Dose Manual Extrinsic Calibration';
            app.DataAcquisitionFolderPathEditFieldLabel = uilabel(app.UIFigure);
            app.DataAcquisitionFolderPathEditFieldLabel.Text = 'Data Acquisition Folder Path';
            app.PathField = uieditfield(app.UIFigure, 'text');
            app.PathField.ValueChangedFcn = createCallbackFcn(app, @PathFieldValueChanged, true);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.WarningLabel = uilabel(app.UIFigure);
            app.WarningLabel.Text = '';
            app.WarningLabel_2 = uilabel(app.UIFigure);
            app.WarningLabel_2.Text = '';
            app.CamNumber12SpinnerLabel = uilabel(app.UIFigure);
            app.CamNumber12SpinnerLabel.Text = 'Cam Number (1,2,...)';
            app.CamField = uispinner(app.UIFigure);
            app.CamField.Limits = [1 5];
            app.CamField.ValueChangedFcn = createCallbackFcn(app, @CamFieldValueChanged, true);
            app.CamField.Value = 1;
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Success = uilabel(app.UIFigure);
            app.Success.Text = '';
            app.SelectPointsButton = uibutton(app.UIFigure, 'push');
            app.SelectPointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointsButtonPushed, true);
            app.SelectPointsButton.Text = 'Select Points';
            app.WarningLabel_3 = uilabel(app.UIFigure);
            app.WarningLabel_3.Text = '';
            app.AutoDetect = uibutton(app.UIFigure, 'push');
            app.AutoDetect.ButtonPushedFcn = createCallbackFcn(app, @AutoDetectButtonPushed, true);
            app.AutoDetect.Text = 'Try Auto Detect';
            app.AutoDetectLabel = uilabel(app.UIFigure);
            app.AutoDetectLabel.Text = '';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = getpoints

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end